classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        ApplicationUIFigure       matlab.ui.Figure
        MobileAppVisualizerPanel  matlab.ui.container.Panel
        REINICIARButton           matlab.ui.control.Button
        AcumulativoSwitch         matlab.ui.control.Switch
        AcumulativoSwitchLabel    matlab.ui.control.Label
        StatusButtonGroup         matlab.ui.container.ButtonGroup
        StatusAmbosButton         matlab.ui.control.RadioButton
        StatusInactivoButton      matlab.ui.control.RadioButton
        StatusActivoButton        matlab.ui.control.RadioButton
        FrecuenciaButtonGroup     matlab.ui.container.ButtonGroup
        AnualButton               matlab.ui.control.RadioButton
        MensualButton             matlab.ui.control.RadioButton
        DiariaButton              matlab.ui.control.RadioButton
        StoreSOButtonGroup        matlab.ui.container.ButtonGroup
        StoreAmbosButton          matlab.ui.control.RadioButton
        StoreiOSButton            matlab.ui.control.RadioButton
        StoreAndroidButton        matlab.ui.control.RadioButton
        PromedioSlider            matlab.ui.control.Slider
        PromedioSliderLabel       matlab.ui.control.Label
        PaisListBox               matlab.ui.control.ListBox
        PasListBoxLabel           matlab.ui.control.Label
        ContinenteListBox         matlab.ui.control.ListBox
        ContinenteListBoxLabel    matlab.ui.control.Label
        UIAxes                    matlab.ui.control.UIAxes
    end


    properties (Access = private)
        isInit logical = 0;
        isDataLoaded logical = 0;
        objDatasetManager;
    end
    
    methods (Access = private)
        
        function inicializar(app)

            % Adding PATH to src\
            pathMods = horzcat(pwd,'\src');
            addpath(pathMods);

            % Loading DatasetManager()
            app.objDatasetManager = DatasetManager(pathMods);

            if app.objDatasetManager.isInit()
                app.isInit = 1; % init when PYENV and data have been loaded
                
                % Displaying data
                app.cargarDatos();

                % Disp info
                disp(horzcat('pyenv.Version: ',char(app.objDatasetManager.getPYENVersion())));
                disp('PathsAdded: ');
                disp('           src\');
                disp(horzcat('Init: ',num2str(app.isInit)));
                disp(horzcat('DataLoaded: ',num2str(app.isDataLoaded)));
            else
                disp('No pyenv found (Unable to execute). Please create a python3.9 virtual env in the project folder!')
            end
        end

        function cargarDatos(app)
            if app.isDataLoaded == 0
                % Adding continents and countries to ListBoxes
                % Using ItemsData to be able to use an index to access data
                app.ContinenteListBox.Items = app.objDatasetManager.getContinents();
                app.ContinenteListBox.ItemsData = 1:length(app.objDatasetManager.getContinents());
                
                app.PaisListBox.Items = app.objDatasetManager.getCountries(1);
                app.PaisListBox.ItemsData = 1:length(app.objDatasetManager.getCountries(1));
                
                % Setting default values
                app.valoresPorDefecto();

                % Plotting
                app.graficar();
                
                app.isDataLoaded = 1;
            end
        end

        % Load default values
        function valoresPorDefecto(app)
            app.ContinenteListBox.Value = 1;
            app.ContinenteListBoxValueChanged();
            app.StoreAmbosButton.Value = 1;
            app.StatusAmbosButton.Value = 1;
            app.MensualButton.Value = 1;
            app.AcumulativoSwitch.Value = 1;
            app.PromedioSlider.Value = 1;
        end

        % Plotting
        function graficar(app)
            
            % Clean all previous plots
            cla(app.UIAxes);

            % Querying dataframe with default movmean = 1
            resultado = app.objDatasetManager.filtrar( ...
                app.ContinenteListBox.Items{app.ContinenteListBox.Value}, ...
                app.PaisListBox.Items{app.PaisListBox.Value}, ...
                app.StoreSOButtonGroup.SelectedObject.Text, ...
                app.StatusButtonGroup.SelectedObject.Text, ...
                app.FrecuenciaButtonGroup.SelectedObject.Text, ...
                1, ...
                app.AcumulativoSwitch.Value);
            
            % Plotting bar with default movmean = 1
            bar(app.UIAxes,resultado{1},resultado{2},'EdgeColor','none');
            hold(app.UIAxes, 'on');

            % Querying dataframe with all filters
            resultado = app.objDatasetManager.filtrar( ...
                app.ContinenteListBox.Items{app.ContinenteListBox.Value}, ...
                app.PaisListBox.Items{app.PaisListBox.Value}, ...
                app.StoreSOButtonGroup.SelectedObject.Text, ...
                app.StatusButtonGroup.SelectedObject.Text, ...
                app.FrecuenciaButtonGroup.SelectedObject.Text, ...
                round(app.PromedioSlider.Value), ...
                app.AcumulativoSwitch.Value);

            % Plotting with all filters
            plot(app.UIAxes,resultado{1},resultado{2});
            hold(app.UIAxes, 'off');
        end

    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.inicializar();
        end

        % Value changed function: ContinenteListBox
        function ContinenteListBoxValueChanged(app, event)
            if app.isInit    
                % if continent change set its countries
                app.PaisListBox.Items = app.objDatasetManager().getCountries(app.ContinenteListBox.Value);
                app.PaisListBox.ItemsData = 1:length(app.objDatasetManager().getCountries(app.ContinenteListBox.Value));
                app.PaisListBox.Value = 1;

                % Plot
                app.graficar();
            else
                app.inicializar();
            end
        end

        % Value changed function: PaisListBox
        function PaisListBoxValueChanged(app, event)
            if app.isInit
                app.graficar();
            else
                app.inicializar();
            end
        end

        % Selection changed function: StoreSOButtonGroup
        function StoreSOButtonGroupSelectionChanged(app, event)
            if app.isInit
                app.graficar();
            else
                app.inicializar();
            end
        end

        % Selection changed function: StatusButtonGroup
        function StatusButtonGroupSelectionChanged(app, event)
            if app.isInit
                app.graficar();
            else
                app.inicializar();
            end
        end

        % Selection changed function: FrecuenciaButtonGroup
        function FrecuenciaButtonGroupSelectionChanged(app, event)
            if app.isInit
                app.graficar();
            else
                app.inicializar();
            end
        end

        % Value changed function: AcumulativoSwitch
        function AcumulativoSwitchValueChanged(app, event)
            if app.isInit
                app.graficar();
            else
                app.inicializar();
            end
        end

        % Value changed function: PromedioSlider
        function PromedioSliderValueChanged(app, event)
            if app.isInit
                app.graficar();
            else
                app.inicializar();
            end
        end

        % Button pushed function: REINICIARButton
        function REINICIARButtonPushed(app, event)
            if app.isInit
                % Reset default values
                app.valoresPorDefecto();

                % Plot
                app.graficar();
            else
                app.inicializar();
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.ApplicationUIFigure = uifigure('Visible', 'off');
            app.ApplicationUIFigure.Name = 'Application';
            app.MobileAppVisualizerPanel = uipanel(app.ApplicationUIFigure);
            app.MobileAppVisualizerPanel.Title = 'MobileAppVisualizer';
            app.UIAxes = uiaxes(app.MobileAppVisualizerPanel);
            title(app.UIAxes, 'DATA')
            xlabel(app.UIAxes, 'Fecha')
            ylabel(app.UIAxes, 'Cantidad')
            app.UIAxes.LineStyleOrder = {'-- s'};
            app.ContinenteListBoxLabel = uilabel(app.MobileAppVisualizerPanel);
            app.ContinenteListBoxLabel.Text = 'Continente';
            app.ContinenteListBox = uilistbox(app.MobileAppVisualizerPanel);
            app.ContinenteListBox.Items = {};
            app.ContinenteListBox.ValueChangedFcn = createCallbackFcn(app, @ContinenteListBoxValueChanged, true);
            app.ContinenteListBox.Value = {};
            app.PasListBoxLabel = uilabel(app.MobileAppVisualizerPanel);
            app.PasListBoxLabel.Text = 'País';
            app.PaisListBox = uilistbox(app.MobileAppVisualizerPanel);
            app.PaisListBox.Items = {};
            app.PaisListBox.ValueChangedFcn = createCallbackFcn(app, @PaisListBoxValueChanged, true);
            app.PaisListBox.Value = {};
            app.PromedioSliderLabel = uilabel(app.MobileAppVisualizerPanel);
            app.PromedioSliderLabel.Text = 'Promedio';
            app.PromedioSlider = uislider(app.MobileAppVisualizerPanel);
            app.PromedioSlider.Limits = [1 15];
            app.PromedioSlider.MajorTicks = [1 2 3 4 5 6 7 8 9 10 11 12 13 14 15];
            app.PromedioSlider.MajorTickLabels = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '13', '14', '15'};
            app.PromedioSlider.ValueChangedFcn = createCallbackFcn(app, @PromedioSliderValueChanged, true);
            app.PromedioSlider.Value = 1;
            app.StoreSOButtonGroup = uibuttongroup(app.MobileAppVisualizerPanel);
            app.StoreSOButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StoreSOButtonGroupSelectionChanged, true);
            app.StoreSOButtonGroup.Title = 'Store - (SO)';
            app.StoreAndroidButton = uiradiobutton(app.StoreSOButtonGroup);
            app.StoreAndroidButton.Text = 'GooglePlay (Android)';
            app.StoreiOSButton = uiradiobutton(app.StoreSOButtonGroup);
            app.StoreiOSButton.Text = 'AppStore (iOS)';
            app.StoreAmbosButton = uiradiobutton(app.StoreSOButtonGroup);
            app.StoreAmbosButton.Text = 'Ambos';
            app.StoreAmbosButton.Value = true;
            app.FrecuenciaButtonGroup = uibuttongroup(app.MobileAppVisualizerPanel);
            app.FrecuenciaButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FrecuenciaButtonGroupSelectionChanged, true);
            app.FrecuenciaButtonGroup.Title = 'Frecuencia';
            app.DiariaButton = uiradiobutton(app.FrecuenciaButtonGroup);
            app.DiariaButton.Text = 'Diaria';
            app.MensualButton = uiradiobutton(app.FrecuenciaButtonGroup);
            app.MensualButton.Text = 'Mensual';
            app.MensualButton.Value = true;
            app.AnualButton = uiradiobutton(app.FrecuenciaButtonGroup);
            app.AnualButton.Text = 'Anual';
            app.StatusButtonGroup = uibuttongroup(app.MobileAppVisualizerPanel);
            app.StatusButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @StatusButtonGroupSelectionChanged, true);
            app.StatusButtonGroup.Title = 'Status';
            app.StatusActivoButton = uiradiobutton(app.StatusButtonGroup);
            app.StatusActivoButton.Text = 'Activo';
            app.StatusInactivoButton = uiradiobutton(app.StatusButtonGroup);
            app.StatusInactivoButton.Text = 'Inactivo';
            app.StatusAmbosButton = uiradiobutton(app.StatusButtonGroup);
            app.StatusAmbosButton.Text = 'Ambos';
            app.StatusAmbosButton.Value = true;
            app.AcumulativoSwitchLabel = uilabel(app.MobileAppVisualizerPanel);
            app.AcumulativoSwitchLabel.Text = 'Acumulativo';
            app.AcumulativoSwitch = uiswitch(app.MobileAppVisualizerPanel, 'slider');
            app.AcumulativoSwitch.ItemsData = [0 1];
            app.AcumulativoSwitch.ValueChangedFcn = createCallbackFcn(app, @AcumulativoSwitchValueChanged, true);
            app.AcumulativoSwitch.Value = 0;
            app.REINICIARButton = uibutton(app.MobileAppVisualizerPanel, 'push');
            app.REINICIARButton.ButtonPushedFcn = createCallbackFcn(app, @REINICIARButtonPushed, true);
            app.REINICIARButton.Text = 'REINICIAR';
            % Show the figure after all components are created
            app.ApplicationUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.ApplicationUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.ApplicationUIFigure)
        end
    end
end